classdef Audio_equalizer_GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        Audio_Equalizer_GUI       matlab.ui.Figure
        Browser                   matlab.ui.container.Panel
        Address                   matlab.ui.control.TextArea
        BrowseButton              matlab.ui.control.Button
        FileAddressLabel          matlab.ui.control.Label
        EqualizerOptionsPanel     matlab.ui.container.Panel
        DeepBassSlider            matlab.ui.control.Slider
        UpperBassSlider           matlab.ui.control.Slider
        MiddleMidrangeSlider      matlab.ui.control.Slider
        PresenceRangeSlider       matlab.ui.control.Slider
        LowBass                   matlab.ui.control.Label
        MidBass                   matlab.ui.control.Label
        PresenceRange             matlab.ui.control.Label
        EditMidBass               matlab.ui.control.NumericEditField
        EditMiddleMidrange        matlab.ui.control.NumericEditField
        EditLowBass               matlab.ui.control.NumericEditField
        EditPresenceRange         matlab.ui.control.NumericEditField
        MiddleMidRange            matlab.ui.control.Label
        LowBassSlider             matlab.ui.control.Slider
        ExtremelyHighEndSlider    matlab.ui.control.Slider
        MidBassSlider             matlab.ui.control.Slider
        LowerMidRangeSlider       matlab.ui.control.Slider
        UpperMidRangeSlider       matlab.ui.control.Slider
        HighEndSlider             matlab.ui.control.Slider
        DeepBass                  matlab.ui.control.Label
        EditDeepBass              matlab.ui.control.NumericEditField
        UpperBass                 matlab.ui.control.Label
        EditUpperBass             matlab.ui.control.NumericEditField
        LowerMidRange             matlab.ui.control.Label
        EditLowerMidRange         matlab.ui.control.NumericEditField
        EditUpperMidRange         matlab.ui.control.NumericEditField
        UpperMidRange             matlab.ui.control.Label
        EditHighEnd               matlab.ui.control.NumericEditField
        HighEnd                   matlab.ui.control.Label
        ExtremelyHighEnd          matlab.ui.control.Label
        EditExtremelyHighEnd      matlab.ui.control.NumericEditField
        SettingsPanel             matlab.ui.container.Panel
        PlayButton                matlab.ui.control.Button
        PauseButton               matlab.ui.control.Button
        ResumeButton              matlab.ui.control.Button
        StopButton                matlab.ui.control.Button
        VolumeSliderLabel         matlab.ui.control.Label
        VolumeSlider              matlab.ui.control.Slider
        VolumeValue               matlab.ui.control.NumericEditField
        FrequencySpectrumsPanel   matlab.ui.container.Panel
        UIAxes                    matlab.ui.control.UIAxes
        UIAxes2                   matlab.ui.control.UIAxes
        PlotOriginalSignalButton  matlab.ui.control.Button
        PlotFilteredSignalButton  matlab.ui.control.Button
    end

    
    methods (Access = private)
        
        function yT = equalizer(~, G1, G2, G3, G4, G5, G6, G7, G8, G9, G10, y, fs)
            
            order = 16;
            
            % Deep Bass Filter
            cutoff = 200;
            a = fir1(order, cutoff/(fs/2),'low');
            k1 = filter(a,1,y);
            y1 = G1 * k1;
            
            % Low Bass Filter
            f1 = 201;
            f2 = 500;
            b1 = fir1(order, [f1/(fs/2) f2/(fs/2)], 'bandpass');
            y2 = G2 * filter(b1,1,y);
            
            % Mid Bass Filter
            f3 = 501;
            f4 = 800;
            b2 = fir1(order, [f3/(fs/2) f4/(fs/2)], 'bandpass');
            y3 = G3 * filter(b2,1,y);
            
            % Upper Bass Filter
            f5 = 801;
            f6 = 2000;
            b3 = fir1(order, [f5/(fs/2) f6/(fs/2)], 'bandpass');
            y4 = G4 * filter(b3,1,y);
            
            % Lower MidRange Filter
            f7 = 2001;
            f8 = 4000;
            b4 = fir1(order, [f7/(fs/2) f8/(fs/2)], 'bandpass');
            y5 = G5 * filter(b4,1,y);
            
            % Middle MidRange Filter
            f9 = 4001;
            f10 = 6000;
            b5 = fir1(order, [f9/(fs/2) f10/(fs/2)], 'bandpass');
            y6 = G6 * filter(b5,1,y);
            
            % Upper Midrange Filter
            f11 = 6001;
            f12 = 8000;
            b6 = fir1(order, [f11/(fs/2) f12/(fs/2)], 'bandpass');
            y7 = G7 * filter(b6,1,y);
            
            % Presence Range Filter
            f13 = 8001;
            f14 = 10000;
            b7 = fir1(order, [f13/(fs/2) f14/(fs/2)], 'bandpass');
            y8 = G8 * filter(b7,1,y);
            
            % High End Filter
            f15 = 10001;
            f16 = 15000;
            b8 = fir1(order, [f15/(fs/2) f16/(fs/2)], 'bandpass');
            y9 = G9 * filter(b8,1,y);
            
            % Extremely High End Filter
            cutoff2 = 15001;
            c = fir1(order, cutoff2/(fs/2), 'high');
            y10 = G10 * filter(c,1,y);
            
            yT = y1 + y2 + y3 + y4 + y5 + y6 + y7 + y8 + y9 + y10;
            
           
        end
        
    end
    

    methods (Access = private)

        % Value changed function: Address
        function AddressValueChanged(app, event)
          %  value = app.Address.Value;
            
        end

        % Button pushed function: BrowseButton
        function BrowseButtonPushed(app, event)
          %  global player 
            global y fs 
            [file, path] = uigetfile('*.mp3','', 'C:\Users\derek\OneDrive\Documents\MATLAB\ECE 460\Project 1\Stranger Things Theme x Childish Gambino by kmlkmljkl (Rap Remix).mp3');

             [y, fs] = audioread(file);
            % player = audioplayer(y,fs);
            
            full_pathname = strcat(path,file);
            app.Address.Value = full_pathname;
        end

        % Value changing function: DeepBassSlider
        function DeepBassSliderMoving(app, event)
            global G1
            app.EditDeepBass.Value = event.Value;
            G1 = app.EditDeepBass.Value;
        end

        % Value changing function: UpperBassSlider
        function UpperBassSliderMoving(app, event)
            global G4
            app.EditUpperBass.Value = event.Value;
            G4 = app.EditUpperBass.Value;
        end

        % Value changing function: MiddleMidrangeSlider
        function MiddleMidRangeSliderMoving(app, event)
            global G6
            app.EditMiddleMidrange.Value = event.Value;
            G6 = app.EditMiddleMidrange.Value;
        end

        % Value changing function: PresenceRangeSlider
        function PresenceRangeSliderMoving(app, event)
            global G8
            app.EditPresenceRange.Value  = event.Value;
            G8 = app.EditPresenceRange.Value;
        end

        % Value changing function: LowBassSlider
        function LowBassSliderMoving(app, event)
            global G2
            app.EditLowBass.Value = event.Value;
            G2 = app.EditLowBass.Value;
        end

        % Value changing function: ExtremelyHighEndSlider
        function ExtremelyHighEndSliderMoving(app, event)
            global G10
            app.EditExtremelyHighEnd.Value = event.Value;
            G10 = app.EditExtremelyHighEnd.Value;
        end

        % Value changing function: MidBassSlider
        function MidBassSliderMoving(app, event)
            global G3
            app.EditMidBass.Value = event.Value;
            G3 = app.EditMidBass.Value;
        end

        % Value changing function: LowerMidRangeSlider
        function LowerMidRangeSliderMoving(app, event)
            global G5
            app.EditLowerMidRange.Value = event.Value;
            G5 = app.EditLowerMidRange.Value;
        end

        % Value changing function: UpperMidRangeSlider
        function UpperMidRangeSliderMoving(app, event)
            global G7
            app.EditUpperMidRange.Value = event.Value;
            G7 = app.EditUpperMidRange.Value;
        end

        % Value changing function: HighEndSlider
        function HighEndSliderMoving(app, event)
            global G9
            app.EditHighEnd.Value = event.Value;
            G9 = app.EditHighEnd.Value;
        end

        % Value changed function: EditDeepBass
        function EditDeepBassChanged(app, event)
            global G1
            app.DeepBassSlider.Value = app.EditDeepBass.Value;
            G1 = app.EditDeepBass.Value;
        end

        % Button pushed function: PlayButton
        function PlayButtonPressed(app, event)
            global player y fs 
            
            G1 = get(app.DeepBassSlider, 'value');
            G2 = get(app.LowBassSlider, 'value');
            G3 = get(app.MidBassSlider, 'value');
            G4 = get(app.UpperBassSlider, 'value');
            G5 = get(app.LowerMidRangeSlider, 'value');
            G6 = get(app.MiddleMidrangeSlider, 'value');
            G7 = get(app.UpperMidRangeSlider, 'value');
            G8 = get(app.PresenceRangeSlider, 'value');
            G9 = get(app.HighEndSlider, 'value');
            G10 = get(app.ExtremelyHighEndSlider, 'value');
            volume = get(app.VolumeSlider, 'value');
            
            
            yT = equalizer(app, G1, G2, G3, G4, G5, G6, G7, G8, G9, G10, y, fs);
            
            player = audioplayer(volume*yT,fs);
            
            play(player);                                            
        end

        % Button pushed function: PauseButton
        function PauseButtionClicked(app, event)
            global player
            pause(player);
        end

        % Button pushed function: ResumeButton
        function ResumeButtonClicked(app, event)
            global player
            resume(player);
        end

        % Button pushed function: StopButton
        function StopButtonClicked(app, event)
            global player
            stop(player);
        end

        % Button pushed function: PlotOriginalSignalButton
        function PlotOriginalSignalButtonPushed(app, event)
            global  y  
            plot(app.UIAxes,y)
        end

        % Value changed function: EditUpperBass
        function EditUpperBassChanged(app, event)
            global G4
            app.UpperBassSlider.Value = app.EditUpperBass.Value;
            G4 = app.EditUpperBass.Value;
        end

        % Value changed function: EditMiddleMidrange
        function EditMiddleMidRangeChanged(app, event)
            global G6
            app.MiddleMidrangeSlider.Value = app.EditMiddleMidrange.Value;
            G6 = app.EditMiddleMidrange.Value;
        end

        % Value changed function: EditPresenceRange
        function EditPresenceRangeChanged(app, event)
            global G8
            app.PresenceRangeSlider.Value = app.EditPresenceRange.Value;
            G8 = app.EditPresenceRange.Value;
        end

        % Value changed function: EditLowBass
        function EditLowBassChanged(app, event)
            global G2
            app.LowBassSlider.Value = app.EditLowBass.Value;
            G2 = app.EditLowBass.Value;
        end

        % Value changed function: EditExtremelyHighEnd
        function EditExtremelyHighEndChanged(app, event)
            global G10
            app.ExtremelyHighEndSlider.Value = app.EditExtremelyHighEnd.Value;
            G10 = app.EditExtremelyHighEnd.Value;
        end

        % Value changed function: EditMidBass
        function EditMidBassChanged(app, event)
            global G3
            app.MidBassSlider.Value = app.EditMidBass.Value;
            G3 = app.EditMidBass.Value;
        end

        % Value changed function: EditLowerMidRange
        function EditLowerMidRangeChanged(app, event)
            global G5
            app.LowerMidRangeSlider.Value = app.EditLowerMidRange.Value;
            G5 = app.EditLowerMidRange.Value;
        end

        % Value changed function: EditUpperMidRange
        function EditUpperMidRangeChanged(app, event)
            global G7
            app.UpperMidRangeSlider.Value = app.EditUpperMidRange.Value;
            G7 = app.EditUpperMidRange.Value;
        end

        % Value changed function: EditHighEnd
        function EditHighEndChanged(app, event)
            global G9
            app.HighEndSlider.Value = app.EditHighEnd.Value;
            G9 = app.EditHighEnd.Value;
        end

        % Value changed function: VolumeSlider
        function VolumeChanged(app, event)
            app.VolumeValue.Value = app.VolumeSlider.Value;
        end

        % Value changed function: VolumeValue
        function VolumeValueChanged(app, event)
            app.VolumeSlider.Value = app.VolumeValue.Value;     
        end

        % Button pushed function: PlotFilteredSignalButton
        function PlotFilteredSignalButtonPressed(app, event)
            global  y fs 
            
            G1 = get(app.DeepBassSlider, 'value');
            G2 = get(app.LowBassSlider, 'value');
            G3 = get(app.MidBassSlider, 'value');
            G4 = get(app.UpperBassSlider, 'value');
            G5 = get(app.LowerMidRangeSlider, 'value');
            G6 = get(app.MiddleMidrangeSlider, 'value');
            G7 = get(app.UpperMidRangeSlider, 'value');
            G8 = get(app.PresenceRangeSlider, 'value');
            G9 = get(app.HighEndSlider, 'value');
            G10 = get(app.ExtremelyHighEndSlider, 'value');  
            
            yt = equalizer(app, G1, G2, G3, G4, G5, G6, G7, G8, G9, G10, y, fs);
            
            plot(app.UIAxes2,yt)
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.Audio_Equalizer_GUI = uifigure;
            app.Audio_Equalizer_GUI.Name = 'UI Figure';
            app.Browser = uipanel(app.Audio_Equalizer_GUI);
            app.Address = uitextarea(app.Browser);
            app.Address.ValueChangedFcn = createCallbackFcn(app, @AddressValueChanged, true);
            app.BrowseButton = uibutton(app.Browser, 'push');
            app.BrowseButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseButtonPushed, true);
            app.BrowseButton.Tooltip = {'browse'};
            app.BrowseButton.Text = 'Browse';
            app.FileAddressLabel = uilabel(app.Browser);
            app.FileAddressLabel.Text = 'File Address';
            app.EqualizerOptionsPanel = uipanel(app.Audio_Equalizer_GUI);
            app.EqualizerOptionsPanel.Title = 'Equalizer Options';
            app.DeepBassSlider = uislider(app.EqualizerOptionsPanel);
            app.DeepBassSlider.Limits = [-1 1];
            app.DeepBassSlider.ValueChangingFcn = createCallbackFcn(app, @DeepBassSliderMoving, true);
            app.DeepBassSlider.Value = 0.1;
            app.UpperBassSlider = uislider(app.EqualizerOptionsPanel);
            app.UpperBassSlider.Limits = [-5 5];
            app.UpperBassSlider.ValueChangingFcn = createCallbackFcn(app, @UpperBassSliderMoving, true);
            app.MiddleMidrangeSlider = uislider(app.EqualizerOptionsPanel);
            app.MiddleMidrangeSlider.Limits = [-5 5];
            app.MiddleMidrangeSlider.ValueChangingFcn = createCallbackFcn(app, @MiddleMidRangeSliderMoving, true);
            app.PresenceRangeSlider = uislider(app.EqualizerOptionsPanel);
            app.PresenceRangeSlider.Limits = [-5 5];
            app.PresenceRangeSlider.ValueChangingFcn = createCallbackFcn(app, @PresenceRangeSliderMoving, true);
            app.LowBass = uilabel(app.EqualizerOptionsPanel);
            app.LowBass.Text = '201-500';
            app.MidBass = uilabel(app.EqualizerOptionsPanel);
            app.MidBass.Text = '501-800';
            app.PresenceRange = uilabel(app.EqualizerOptionsPanel);
            app.PresenceRange.Text = '8001-10K';
            app.EditMidBass = uieditfield(app.EqualizerOptionsPanel, 'numeric');
            app.EditMidBass.ValueChangedFcn = createCallbackFcn(app, @EditMidBassChanged, true);
            app.EditMiddleMidrange = uieditfield(app.EqualizerOptionsPanel, 'numeric');
            app.EditMiddleMidrange.ValueChangedFcn = createCallbackFcn(app, @EditMiddleMidRangeChanged, true);
            app.EditLowBass = uieditfield(app.EqualizerOptionsPanel, 'numeric');
            app.EditLowBass.ValueChangedFcn = createCallbackFcn(app, @EditLowBassChanged, true);
            app.EditPresenceRange = uieditfield(app.EqualizerOptionsPanel, 'numeric');
            app.EditPresenceRange.ValueChangedFcn = createCallbackFcn(app, @EditPresenceRangeChanged, true);
            app.MiddleMidRange = uilabel(app.EqualizerOptionsPanel);
            app.MiddleMidRange.Text = '4001-6K';
            app.LowBassSlider = uislider(app.EqualizerOptionsPanel);
            app.LowBassSlider.Limits = [-5 5];
            app.LowBassSlider.ValueChangingFcn = createCallbackFcn(app, @LowBassSliderMoving, true);
            app.ExtremelyHighEndSlider = uislider(app.EqualizerOptionsPanel);
            app.ExtremelyHighEndSlider.Limits = [-5 5];
            app.ExtremelyHighEndSlider.ValueChangingFcn = createCallbackFcn(app, @ExtremelyHighEndSliderMoving, true);
            app.MidBassSlider = uislider(app.EqualizerOptionsPanel);
            app.MidBassSlider.Limits = [-5 5];
            app.MidBassSlider.ValueChangingFcn = createCallbackFcn(app, @MidBassSliderMoving, true);
            app.LowerMidRangeSlider = uislider(app.EqualizerOptionsPanel);
            app.LowerMidRangeSlider.Limits = [-5 5];
            app.LowerMidRangeSlider.ValueChangingFcn = createCallbackFcn(app, @LowerMidRangeSliderMoving, true);
            app.UpperMidRangeSlider = uislider(app.EqualizerOptionsPanel);
            app.UpperMidRangeSlider.Limits = [-5 5];
            app.UpperMidRangeSlider.ValueChangingFcn = createCallbackFcn(app, @UpperMidRangeSliderMoving, true);
            app.HighEndSlider = uislider(app.EqualizerOptionsPanel);
            app.HighEndSlider.Limits = [-5 5];
            app.HighEndSlider.ValueChangingFcn = createCallbackFcn(app, @HighEndSliderMoving, true);
            app.DeepBass = uilabel(app.EqualizerOptionsPanel);
            app.DeepBass.Text = '<200';
            app.EditDeepBass = uieditfield(app.EqualizerOptionsPanel, 'numeric');
            app.EditDeepBass.ValueChangedFcn = createCallbackFcn(app, @EditDeepBassChanged, true);
            app.UpperBass = uilabel(app.EqualizerOptionsPanel);
            app.UpperBass.Text = '801-2K';
            app.EditUpperBass = uieditfield(app.EqualizerOptionsPanel, 'numeric');
            app.EditUpperBass.ValueChangedFcn = createCallbackFcn(app, @EditUpperBassChanged, true);
            app.LowerMidRange = uilabel(app.EqualizerOptionsPanel);
            app.LowerMidRange.Text = '2001-4K';
            app.EditLowerMidRange = uieditfield(app.EqualizerOptionsPanel, 'numeric');
            app.EditLowerMidRange.ValueChangedFcn = createCallbackFcn(app, @EditLowerMidRangeChanged, true);
            app.EditUpperMidRange = uieditfield(app.EqualizerOptionsPanel, 'numeric');
            app.EditUpperMidRange.ValueChangedFcn = createCallbackFcn(app, @EditUpperMidRangeChanged, true);
            app.UpperMidRange = uilabel(app.EqualizerOptionsPanel);
            app.UpperMidRange.Text = '6001-8K';
            app.EditHighEnd = uieditfield(app.EqualizerOptionsPanel, 'numeric');
            app.EditHighEnd.ValueChangedFcn = createCallbackFcn(app, @EditHighEndChanged, true);
            app.HighEnd = uilabel(app.EqualizerOptionsPanel);
            app.HighEnd.Text = '10001-15K';
            app.ExtremelyHighEnd = uilabel(app.EqualizerOptionsPanel);
            app.ExtremelyHighEnd.Text = '>15001';
            app.EditExtremelyHighEnd = uieditfield(app.EqualizerOptionsPanel, 'numeric');
            app.EditExtremelyHighEnd.ValueChangedFcn = createCallbackFcn(app, @EditExtremelyHighEndChanged, true);
            app.SettingsPanel = uipanel(app.Audio_Equalizer_GUI);
            app.SettingsPanel.Title = 'Settings';
            app.PlayButton = uibutton(app.SettingsPanel, 'push');
            app.PlayButton.ButtonPushedFcn = createCallbackFcn(app, @PlayButtonPressed, true);
            app.PlayButton.Text = 'Play';
            app.PauseButton = uibutton(app.SettingsPanel, 'push');
            app.PauseButton.ButtonPushedFcn = createCallbackFcn(app, @PauseButtionClicked, true);
            app.PauseButton.Text = 'Pause';
            app.ResumeButton = uibutton(app.SettingsPanel, 'push');
            app.ResumeButton.ButtonPushedFcn = createCallbackFcn(app, @ResumeButtonClicked, true);
            app.ResumeButton.Text = 'Resume';
            app.StopButton = uibutton(app.SettingsPanel, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonClicked, true);
            app.StopButton.Text = 'Stop';
            app.VolumeSliderLabel = uilabel(app.SettingsPanel);
            app.VolumeSliderLabel.Text = 'Volume';
            app.VolumeSlider = uislider(app.SettingsPanel);
            app.VolumeSlider.Limits = [0 1];
            app.VolumeSlider.ValueChangedFcn = createCallbackFcn(app, @VolumeChanged, true);
            app.VolumeSlider.Value = 1;
            app.VolumeValue = uieditfield(app.SettingsPanel, 'numeric');
            app.VolumeValue.Limits = [0 1];
            app.VolumeValue.ValueChangedFcn = createCallbackFcn(app, @VolumeValueChanged, true);
            app.VolumeValue.Value = 1;
            app.FrequencySpectrumsPanel = uipanel(app.Audio_Equalizer_GUI);
            app.FrequencySpectrumsPanel.Title = 'Frequency Spectrums';
            app.UIAxes = uiaxes(app.FrequencySpectrumsPanel);
            title(app.UIAxes, 'Original Signal')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.UIAxes2 = uiaxes(app.FrequencySpectrumsPanel);
            title(app.UIAxes2, 'Filtered Signal')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            app.PlotOriginalSignalButton = uibutton(app.FrequencySpectrumsPanel, 'push');
            app.PlotOriginalSignalButton.ButtonPushedFcn = createCallbackFcn(app, @PlotOriginalSignalButtonPushed, true);
            app.PlotOriginalSignalButton.Text = 'Plot Original Signal';
            app.PlotFilteredSignalButton = uibutton(app.FrequencySpectrumsPanel, 'push');
            app.PlotFilteredSignalButton.ButtonPushedFcn = createCallbackFcn(app, @PlotFilteredSignalButtonPressed, true);
            app.PlotFilteredSignalButton.Text = 'Plot Filtered Signal';
        end
    end

    methods (Access = public)

        % Construct app
        function app = Audio_equalizer_GUI

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.Audio_Equalizer_GUI)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.Audio_Equalizer_GUI)
        end
    end
end